classdef app4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        AmplitudeKnobLabel           matlab.ui.control.Label
        AmplitudeKnob                matlab.ui.control.Knob
        FrequencyKnobLabel           matlab.ui.control.Label
        FrequencyKnob                matlab.ui.control.Knob
        UIAxes                       matlab.ui.control.UIAxes
        Button                       matlab.ui.control.Button
        SITLABCRODESIGNLabel         matlab.ui.control.Label
        TypeofWaveformDropDownLabel  matlab.ui.control.Label
        TypeofWaveformDropDown       matlab.ui.control.DropDown
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: AmplitudeKnob
        function AmplitudeKnobValueChanged(app, event)
            value = app.AmplitudeKnob.Value;
    
        end

        % Value changed function: FrequencyKnob
        function FrequencyKnobValueChanged(app, event)
            value = app.FrequencyKnob.Value;
          
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
         t=0:0.01:5*pi; 
         if isequal(app.TypeofWaveformDropDown.Value,'Sine')
        y=app.AmplitudeKnob.Value*sin(2*pi*app.FrequencyKnob.Value*t)
        else
          y=app.AmplitudeKnob.Value*square(2*pi*app.FrequencyKnob.Value*t)  
        end
        plot(app.UIAxes,t,y)
        
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.AmplitudeKnobLabel = uilabel(app.UIFigure);
            app.AmplitudeKnobLabel.Text = 'Amplitude';
            app.AmplitudeKnob = uiknob(app.UIFigure, 'continuous');
            app.AmplitudeKnob.Limits = [0 10];
            app.AmplitudeKnob.ValueChangedFcn = createCallbackFcn(app, @AmplitudeKnobValueChanged, true);
            app.FrequencyKnobLabel = uilabel(app.UIFigure);
            app.FrequencyKnobLabel.Text = 'Frequency';
            app.FrequencyKnob = uiknob(app.UIFigure, 'continuous');
            app.FrequencyKnob.ValueChangedFcn = createCallbackFcn(app, @FrequencyKnobValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Frequency')
            ylabel(app.UIAxes, 'Amplitude')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.SITLABCRODESIGNLabel = uilabel(app.UIFigure);
            app.SITLABCRODESIGNLabel.Text = 'SIT LAB CRO DESIGN';
            app.TypeofWaveformDropDownLabel = uilabel(app.UIFigure);
            app.TypeofWaveformDropDownLabel.Text = 'TypeofWaveform';
            app.TypeofWaveformDropDown = uidropdown(app.UIFigure);
            app.TypeofWaveformDropDown.Items = {'sine', 'square', ''};
            app.TypeofWaveformDropDown.Value = 'sine';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end